classdef bciapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BCIUIFigure             matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        AdquisicinTab           matlab.ui.container.Tab
        AdquisicinPanel         matlab.ui.container.Panel
        ploteeg                 matlab.ui.control.UIAxes
        play                    matlab.ui.control.Button
        stop                    matlab.ui.control.Button
        save                    matlab.ui.control.Button
        clear                   matlab.ui.control.Button
        CanalesListBoxLabel     matlab.ui.control.Label
        CanalesListBox          matlab.ui.control.ListBox
        filter                  matlab.ui.control.Button
        load                    matlab.ui.control.Button
        PlayLabel               matlab.ui.control.Label
        StopLabel               matlab.ui.control.Label
        SaveLabel               matlab.ui.control.Label
        ClearLabel              matlab.ui.control.Label
        FilterLabel             matlab.ui.control.Label
        LoadLabel               matlab.ui.control.Label
        EntrenamientoTab        matlab.ui.container.Tab
        Panel                   matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        inicioentr              matlab.ui.control.Button
        inicioentrLabel         matlab.ui.control.Label
        inicioentrLabel_2       matlab.ui.control.Label
        stopentrLabel           matlab.ui.control.Label
        stopentrLabel_2         matlab.ui.control.Label
        stopentr                matlab.ui.control.Button
        JuegoTab                matlab.ui.container.Tab
        UIAxes2                 matlab.ui.control.UIAxes
        Texto                   matlab.ui.control.TextArea
        mune                    matlab.ui.control.UIAxes
        bvack                   matlab.ui.control.Button
        nivel2                  matlab.ui.control.Button
        stopentr_2              matlab.ui.control.Button
        nivel3                  matlab.ui.control.Button
        JuegoButtonGroup        matlab.ui.container.ButtonGroup
        iniciojuego             matlab.ui.control.Button
        stopentrLabel_3         matlab.ui.control.Label
        stopentrLabel_4         matlab.ui.control.Label
        GiraraladerechaLabel    matlab.ui.control.Label
        GiraralaizquierdaLabel  matlab.ui.control.Label
        AvanzarLabel            matlab.ui.control.Label
        DetenerLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        imder
        imiz
        n1op1
        n1op2
        n1op3
        white
        gds_interface
        gusbamp_config
        jug_der
        est1
        est2
        est3
        est4app
        estimulo
        lab
        FONDO
        MUNE
        lables_SVM
    end
    

    methods (Access = private)

        % Button pushed function: bvack
        function bvackButtonPushed(app, event)
            app.JuegoButtonGroup.Visible='on';
            app.Texto.Visible='on';
            app.bvack.Visible='off';
            app.stopentr_2.Visible='off';
            app.stopentrLabel_3.Visible='off';
            app.stopentrLabel_4.Visible= 'off';
            app.nivel2.Visible='off';
            app.AvanzarLabel.Visible= 'off';
            app.GiraraladerechaLabel.Visible= 'off';
            app.GiraralaizquierdaLabel.Visible= 'off';
            app.DetenerLabel.Visible= 'off';
            app.white = imread('blanco.PNG');
            imshow(app.white,'Parent',app.UIAxes2);
            imshow(app.white,'Parent',app.mune);
            set(app.UIAxes2,'BackgroundColor','[1,1,1]');
            set(app.mune,'BackgroundColor','[1,1,1]');
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            cla(app.ploteeg);
        end

        % Button pushed function: inicioentr
        function inicioentrButtonPushed(app, event)
            app.inicioentr.Visible='off';
            app.inicioentrLabel.Visible='off';
            app.inicioentrLabel_2.Visible='off';
            app.stopentr.Enable='on';
            app.stopentr.Visible='on';
            app.stopentrLabel.Visible='on';
            app.stopentrLabel_2.Visible='on';
            
            %Inicio adquisición
            % objeto gtecDeviceInterface
            app.gds_interface = gtecDeviceInterface();
            
            % configuración de la conexión
            app.gds_interface.IPAddressHost = '127.0.0.1';
            app.gds_interface.IPAddressLocal = '127.0.0.1';
            app.gds_interface.LocalPort = 50224;
            app.gds_interface.HostPort = 50223;
            
            % obtener dispositivos conectados
            connected_devices = app.gds_interface.GetConnectedDevices();
            
            % configuración del objeto g.USBamp
            app.gusbamp_config = gUSBampDeviceConfiguration();
            app.gusbamp_config.Name = connected_devices(1,1).Name;
            
            % habilitar el uso de funciones de la interfaz
            app.gds_interface.DeviceConfigurations = app.gusbamp_config;
            
            % obtener canales disponibles
            available_channels = app.gds_interface.GetAvailableChannels();
            
            % configuraciones de muestreo y para adquirir 16 analog channels.
            app.gusbamp_config.SamplingRate = 256;
            app.gusbamp_config.NumberOfScans = 8;
            Fs = app.gusbamp_config.SamplingRate; %Frecuencia de muestreo
            n_scans = app.gusbamp_config.NumberOfScans; %Número de scans correspondiente a Fs
            
            app.gusbamp_config.CommonGround = false(1,4);
            app.gusbamp_config.CommonReference = false(1,4);
            app.gusbamp_config.ShortCutEnabled = false;
            app.gusbamp_config.CounterEnabled = false;
            app.gusbamp_config.TriggerEnabled = false;
            
            for i=1:size(app.gusbamp_config.Channels,2)
                if (available_channels(1,i))
                    app.gusbamp_config.Channels(1,i).Available = true; %canal disponible
                    app.gusbamp_config.Channels(1,i).Acquire = true;  %seleccionar canal para adquisición
                    % no se hace uso de filtros
                    app.gusbamp_config.Channels(1,i).BandpassFilterIndex = -1;
                    app.gusbamp_config.Channels(1,i).NotchFilterIndex = -1;
                    % no se hace uso de canal bipolar
                    app.gusbamp_config.Channels(1,i).BipolarChannel = 0;
                end
            end
            
            %  configuración para inicio de adquisición de datos
            app.gds_interface.DeviceConfigurations = app.gusbamp_config;
            app.gds_interface.SetConfiguration();
            app.gds_interface.StartDataAcquisition(); %Inicio de adquisición
            
            % adquisición data
            samples_acquired = 0;
            s_wind= 1200*Fs; %longitud de la ventana de análisis 20 min=1200
            sig = single(zeros(s_wind, 16)); %señal
            %Filtrado - filtro butter orden 8
            hp_filt = designfilt('highpassiir', 'FilterOrder', 8, ...
                'HalfPowerFrequency', 0.5, 'DesignMethod', 'butter', 'SampleRate',Fs);
            
            % Vector Estímulos
            s1 = ones(1,10); %2x10, 20 segundos
            s2 = 2*ones(1,10);
            s3 = 3*ones(1,10);
            s4 = 4*ones(1,10);
            % Vector Feedback
            f1 = 1*ones(1,10); %2x10, 20 segundos
            f2 = 2*ones(1,10);
            f3 = 3*ones(1,10);
            f4 = 4*ones(1,10);
            % Vector Descanso
            d1 = zeros(1,20); %40 segundos
            
            %Estímulos
            stim = [s1,d1,s2,d1,s3,d1,s4,d1];
            stim = repmat(stim,1,5);
            %Feedbacks
            feed = [f1,d1,f2,d1,f3,d1,f4,d1];
            feed = repmat(feed,1,5);
            %Matriz de eventos
            m_event=zeros(length(stim),512,16);
            %             ix=1;
            %             ix1=1;
            while (samples_acquired < s_wind ) %Adquisición por 10min
                try
                    [scans_received, data] = app.gds_interface.GetData(n_scans);
                    
                    sig((samples_acquired + 1) : (samples_acquired + scans_received), :) = data;
                catch ME
                    disp(ME.message);
                    break;
                end
                
                samples_acquired = samples_acquired + scans_received;
                
                
                %Estímulos
                if mod(samples_acquired,512) == 0 %estímulo, 2seg
                    
                    %                     vtestif1(ix,1) = samples_acquired/512;
                    %                     vtestif1(ix,2) = stim(samples_acquired/512);
                    %                     vtestif1(ix,3) = samples_acquired;
                    %                     ix=ix+1;
                    
                    
                    if stim(samples_acquired/512) == 1 %Para primer estímulo
                        app.est1 = imread('fondoder1.PNG');
                        set(app.UIAxes,'BackgroundColor','[0.5686,0.8431,0.5373]');
                        imshow(app.est1,'Parent',app.UIAxes);
                        
                    elseif stim(samples_acquired/512) == 2 %Para segundo estímulo
                        app.est1 = imread('fondoiz1.PNG');
                        set(app.UIAxes,'BackgroundColor','[0.5686,0.8431,0.5373]');
                        imshow(app.est1,'Parent',app.UIAxes);
                        
                    elseif stim(samples_acquired/512) == 3 %Para tercer estímulo
                        app.est1 = imread('fondodet1.PNG');
                        set(app.UIAxes,'BackgroundColor','[0.5686,0.8431,0.5373]');
                        imshow(app.est1,'Parent',app.UIAxes);
                        
                    elseif stim(samples_acquired/512) == 4 %Para cuarto estímulo
                        app.est1 = imread('fondoaf1.PNG');
                        set(app.UIAxes,'BackgroundColor','[0.5686,0.8431,0.5373]');
                        imshow(app.est1,'Parent',app.UIAxes);
                        
                    elseif stim(samples_acquired/512) == 0 %Para descanso
                        app.est1 = imread('fondov.PNG');
                        set(app.UIAxes,'BackgroundColor','[0.5686,0.8431,0.5373]');
                        imshow(app.est1,'Parent',app.UIAxes);
                    end
                    s_filt= filtfilt(hp_filt,double(sig(1:samples_acquired,:))); %señal filtrada
                    m_event(samples_acquired/512,:,:)= s_filt(samples_acquired-512+1:samples_acquired,:); %matriz de enventos
                    
                    
                elseif mod(samples_acquired,256) == 0 && samples_acquired > 256 %1seg
                    
                    %                     vtestif2(ix1,1) = samples_acquired/512-1;
                    %                     vtestif2(ix1,2) = feed(samples_acquired/512-1);
                    %                     vtestif2(ix1,3) = samples_acquired;
                    %                     ix1=ix1+1;
                    
                    if feed(samples_acquired/512-1) == 1 %Para primer feedback
                        app.est1 = imread('fondoder2.PNG');
                        set(app.UIAxes,'BackgroundColor','[0.5686,0.8431,0.5373]');
                        imshow(app.est1,'Parent',app.UIAxes);
                        
                    elseif feed(samples_acquired/512-1) == 2 %Para segundo feedback
                        app.est1 = imread('fondoiz2.PNG');
                        set(app.UIAxes,'BackgroundColor','[0.5686,0.8431,0.5373]');
                        imshow(app.est1,'Parent',app.UIAxes);
                        
                    elseif feed(samples_acquired/512-1) == 3 %Para tercer feedback
                        app.est1 = imread('fondodet2.PNG');
                        set(app.UIAxes,'BackgroundColor','[0.5686,0.8431,0.5373]');
                        imshow(app.est1,'Parent',app.UIAxes);
                        
                    elseif feed(samples_acquired/512-1) == 4 %Para cuarto feedback
                        app.est1 = imread('fondoaf2.PNG');
                        set(app.UIAxes,'BackgroundColor','[0.5686,0.8431,0.5373]');
                        imshow(app.est1,'Parent',app.UIAxes);
                        
                    elseif feed(samples_acquired/512-1) == 0 %Para descanso
                        app.est1 = imread('fondov.PNG');
                        set(app.UIAxes,'BackgroundColor','[0.5686,0.8431,0.5373]');
                        imshow(app.est1,'Parent',app.UIAxes);
                    end
                    
                end
                
                
            end %end while
            
            %Matriz de eventos y stim sin datos de descanso
            m_event(stim==0,:,:) = [];
            stim(stim==0)=[];
            
            
            %Extracción de características
            m_mean = mean(m_event,2); %promedio
            p_mean = permute(m_mean,[2 1 3]);
            m_mean = squeeze(p_mean); %matriz promedio
            
            m_std = std(m_event,[],2); %desviasión estándar
            p_std = permute(m_std,[2 1 3]);
            m_std = squeeze(p_std); %matriz desviasión estándar
            
            for f=1:size(m_event(1))
                mt_psd= m_event(f,:,:);
                mt_psd= squeeze(mt_psd);
                m_psd= pwelch(mt_psd,512,256,399);
            end
            
            %Matriz de Características
            m_Carac = [m_mean,m_std,m_psd];
            
            %Labels
            tag = permute(stim,[2 1]);
            
            %Cross Validation / SVM
            %SVM_Model = fitcecoc(m_Carac,tag,'Crossval','on','KFold',5); %modelo y cross validation
            %             genError = kfoldLoss(SVM_Model)
            %             save SVM_Model;
            
            %Cross Validation / LDA
            LDA_Model= fitcdiscr(m_Carac,tag,'Crossval','on','KFold',10);
            ldatrainError = kfoldLoss(LDA_Model);
            ldatrainAccuracy = 1-ldatrainError;
            
            % detener la adquisición
            app.gds_interface.StopDataAcquisition();
            
            % clean up
            delete(app.gds_interface);
            clearvars app.gds_interface;
            clearvars app.gusbamp_config;
            clearvars data;
            % pantalla de inicio
            app.Panel.Visible='on';
            app.inicioentr.Visible='on';
            app.inicioentrLabel.Visible='on';
            app.inicioentrLabel_2.Visible='on';
            app.stopentr.Enable='off';
            app.stopentr.Visible='off';
            app.stopentrLabel.Visible='off';
            app.stopentrLabel_2.Visible='off';
            app.imder = imread('blanco.PNG');
            imshow(app.imder,'Parent',app.UIAxes);
            set(app.UIAxes,'BackgroundColor','[1,1,1]');
            
        end

        % Button pushed function: iniciojuego
        function iniciojuegoButtonPushed(app, event)
            app.JuegoButtonGroup.Visible='off';
            app.Texto.Visible='off';
            app.stopentr_2.Visible= 'on';
            app.stopentr_2.Enable= 'on';
            app.stopentrLabel_3.Visible='on';
            app.stopentrLabel_4.Visible='on';
            
            %Inicio adquisición
            % objeto gtecDeviceInterface
            app.gds_interface = gtecDeviceInterface();
            
            % configuración de la conexión
            app.gds_interface.IPAddressHost = '127.0.0.1';
            app.gds_interface.IPAddressLocal = '127.0.0.1';
            app.gds_interface.LocalPort = 50224;
            app.gds_interface.HostPort = 50223;
            
            % obtener dispositivos conectados
            connected_devices = app.gds_interface.GetConnectedDevices();
            
            % configuración del objeto g.USBamp
            app.gusbamp_config = gUSBampDeviceConfiguration();
            app.gusbamp_config.Name = connected_devices(1,1).Name;
            
            % habilitar el uso de funciones de la interfaz
            app.gds_interface.DeviceConfigurations = app.gusbamp_config;
            
            % obtener canales disponibles
            available_channels = app.gds_interface.GetAvailableChannels();
            
            % configuraciones de muestreo y para adquirir 16 analog channels.
            app.gusbamp_config.SamplingRate = 256;
            app.gusbamp_config.NumberOfScans = 8;
            Fs = app.gusbamp_config.SamplingRate; %Frecuencia de muestreo
            n_scans = app.gusbamp_config.NumberOfScans; %Número de scans correspondiente a Fs
            
            app.gusbamp_config.CommonGround = false(1,4);
            app.gusbamp_config.CommonReference = false(1,4);
            app.gusbamp_config.ShortCutEnabled = false;
            app.gusbamp_config.CounterEnabled = false;
            app.gusbamp_config.TriggerEnabled = false;
            
            for i=1:size(app.gusbamp_config.Channels,2)
                if (available_channels(1,i))
                    app.gusbamp_config.Channels(1,i).Available = true; %canal disponible
                    app.gusbamp_config.Channels(1,i).Acquire = true;  %seleccionar canal para adquisición
                    % no se hace uso de filtros
                    app.gusbamp_config.Channels(1,i).BandpassFilterIndex = -1;
                    app.gusbamp_config.Channels(1,i).NotchFilterIndex = -1;
                    % no se hace uso de canal bipolar
                    app.gusbamp_config.Channels(1,i).BipolarChannel = 0;
                end
            end
            
            %  configuración para inicio de adquisición de datos
            app.gds_interface.DeviceConfigurations = app.gusbamp_config;
            app.gds_interface.SetConfiguration();
            app.gds_interface.StartDataAcquisition(); %Inicio de adquisición
            
            % adquisición data
            samples_acquired = 0;
            s_wind= 1000*Fs; %longitud de la ventana de análisis 3.3min=198seg
            sig = single(zeros(s_wind, 16)); %señal
            %Filtrado - filtro butter orden 8
            hp_filt = designfilt('highpassiir', 'FilterOrder', 8, ...
                'HalfPowerFrequency', 0.5, 'DesignMethod', 'butter', 'SampleRate',Fs);
            
            a= rand(1)
            load LDA_Model; %modelo SVM
            %             load labels;
            
            %Vector de movimientos N1 - opción 1
            movn1_op1 = [1,2,3,4,5,6];
            cmov= 1; %contador vector de movimientos
            
            %Labels
            labels_SVM = [0];
            clabels= 1; %contador labels
            v_event=zeros(1,512,16);
            
            
            while (samples_acquired < s_wind ) %Adquisición por 10min
                try
                    [scans_received, data] = app.gds_interface.GetData(n_scans);
                    
                    sig((samples_acquired + 1) : (samples_acquired + scans_received), :) = data;
                catch ME
                    disp(ME.message);
                    break;
                end
                
                samples_acquired = samples_acquired + scans_received;
                
                %Nivel 1
                if mod(samples_acquired,512) == 0 %adquirir cada 2 seg
                    
                    %Opción 1 - Nivel 1
                    if a>= 0 && a <= 0.33 %Nivel 1 - Opción 1
                        
                        %Fin del Nivel 
                        if clabels > cmov 
                            app.nivel2.Enable= 'on'; 
                            app.nivel2.Visible='on';
                            s_wind=0;
                            %Opción 1 Nivel 1 - Posición Inicial 
                        elseif movn1_op1(samples_acquired/512) == movn1_op1(cmov) 
                            
                            %inicial 
                            if movn1_op1(cmov) == 1 
                                if labels_SVM(clabels) == 0 
                                    app.FONDO = imread('IN11.PNG'); 
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]'); 
                                    imshow(app.FONDO,'Parent',app.UIAxes2); 
                                    app.MUNE = imread('m1.PNG'); 
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]'); 
                                    title(app.mune, []); 
                                    xlabel(app.mune, []); 
                                    ylabel(app.mune, []); 
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [555 120 80 140]; 
                                    clabels=clabels+1; 
                                else 
                                    app.FONDO = imread('IN11.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]'); 
                                    imshow(app.FONDO,'Parent',app.UIAxes2); 
                                    app.MUNE = imread('m1.PNG'); 
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]'); 
                                    title(app.mune, []);  
                                    xlabel(app.mune, []); 
                                    ylabel(app.mune, []); 
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune); 
                                    app.mune.Position = [555 120 80 140]; 
                                    
                                end 
                                app.AvanzarLabel.Visible= 'on'; 
                                app.GiraraladerechaLabel.Visible= 'off'; 
                                app.GiraralaizquierdaLabel.Visible= 'off'; 
                                app.DetenerLabel.Visible= 'off';
                            end 
                            
                            %Movimiento 1 - Adelante 
                            if movn1_op1(cmov) == 2
                                if labels_SVM(clabels) == 4 
                                    app.FONDO = imread('IN11.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]'); 
                                    imshow(app.FONDO,'Parent',app.UIAxes2); 
                                    app.MUNE = imread('m1.PNG'); 
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]'); 
                                    title(app.mune, []); 
                                    xlabel(app.mune, []); 
                                    ylabel(app.mune, []); 
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune); 
                                    app.mune.Position = [555 260 80 140]; 
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else 
                                    app.FONDO = imread('IN11.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]'); 
                                    imshow(app.FONDO,'Parent',app.UIAxes2); 
                                    app.MUNE = imread('m1.PNG'); 
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]'); 
                                    title(app.mune, []);
                                    xlabel(app.mune, []); 
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune); 
                                    app.mune.Position = [555 120 80 140];  
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)]; 
                                    clabels=clabels+1; 
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end 
                                
                            end     
                            %Movimiento 2 - Derecha 
                            if movn1_op1(cmov) == 3
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN11.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);  
                                    xlabel(app.mune, []); 
                                    ylabel(app.mune, []); 
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune); 
                                    app.mune.Position = [555 260 100 150];
                                    clabels=clabels+1; 
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
            
                                else 
                                    app.FONDO = imread('IN11.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2); 
                                    app.MUNE = imread('m1.PNG'); 
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]'); 
                                    title(app.mune, []); 
                                    xlabel(app.mune, []); 
                                    ylabel(app.mune, []); 
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune); 
                                    app.mune.Position = [555 260 80 140]; 
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)]; 
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
            
                                end 
                            end     
                            %Movimiento 3 - Adelante 
                            if movn1_op1(cmov) == 4
                                if labels_SVM(clabels) == 4 
                                    app.FONDO = imread('IN11.PNG'); 
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]'); 
                                    imshow(app.FONDO,'Parent',app.UIAxes2); 
                                    app.MUNE = imread('mi.PNG'); 
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]'); 
                                    title(app.mune, []); 
                                    xlabel(app.mune, []); 
                                    ylabel(app.mune, []); 
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune); 
                                    app.mune.Position = [690 290 100 100]; 
                                    clabels=clabels+1; 
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
            
                                else 
                                    app.FONDO = imread('IN11.PNG'); 
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2); 
                                    app.MUNE = imread('mi.PNG'); 
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]'); 
                                    title(app.mune, []); 
                                    xlabel(app.mune, []); 
                                    ylabel(app.mune, []); 
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [555 260 100 150];
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                    
                                end  
                            end     
                            %Movimiento 4 - Izquierda 
                            if movn1_op1(cmov) == 5
                                if labels_SVM(clabels) == 2 
                                    app.FONDO = imread('IN11.PNG'); 
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]'); 
                                    imshow(app.FONDO,'Parent',app.UIAxes2); 
                                    app.MUNE = imread('m1.PNG'); 
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]'); 
                                    title(app.mune, []); 
                                    xlabel(app.mune, []); 
                                    ylabel(app.mune, []); 
                                    app.mune.XAxis.TickLabels={}; 
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune); 
                                    app.mune.Position = [675 280 100 120]; 
                                    clabels=clabels+1; 
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else 
                                    app.FONDO = imread('IN11.PNG'); 
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]'); 
                                    imshow(app.FONDO,'Parent',app.UIAxes2); 
                                    app.MUNE = imread('mi.PNG'); 
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]'); 
                                    title(app.mune, []); 
                                    xlabel(app.mune, []); 
                                    ylabel(app.mune, []); 
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune); 
                                    app.mune.Position = [690 290 100 100]; 
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)]; 
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off'; 
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end 
                            end     
                            %Movimiento 5 - Detenerse 
                            if movn1_op1(cmov) == 6 
                                if labels_SVM(clabels) == 3 
                                    app.FONDO = imread('INB11.PNG'); 
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]'); 
                                    imshow(app.FONDO,'Parent',app.UIAxes2); 
                                    app.MUNE = imread('m1.PNG'); 
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]'); 
                                    title(app.mune, []); 
                                    xlabel(app.mune, []); 
                                    ylabel(app.mune, []); 
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune); 
                                    app.mune.Position = [675 280 100 120]; 
                                    clabels=clabels+5; 
                                    app.AvanzarLabel.Visible= 'off'; 
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else 
                                    app.FONDO = imread('IN11.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2); 
                                    app.MUNE = imread('m1.PNG'); 
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]'); 
                                    title(app.mune, []); 
                                    xlabel(app.mune, []); 
                                    ylabel(app.mune, []); 
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune); 
                                    app.mune.Position = [675 280 100 120]; 
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)]; 
                                    clabels=clabels+1; 
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end 
                            end 
                        end 
                    end
           
                    
                    %Opción 2 - Nivel 1
                    if a>= 0.33 && a <= 0.66 %Nivel 1 - Opción 2
                        
                        %Fin del Nivel
                        if clabels > cmov
                            app.nivel2.Enable= 'on';
                            app.nivel2.Visible='on';
                            s_wind=0;
                            %Opción 2 Nivel 1 - Posición Inicial
                        elseif movn1_op1(samples_acquired/512) == movn1_op1(cmov)
                            
                            %inicial
                            if movn1_op1(cmov) == 1
                                if labels_SVM(clabels) == 0
                                    app.FONDO = imread('IN12.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [770 100 95 120];
                                    clabels=clabels+1;
                                else
                                    app.FONDO = imread('IN12.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [770 100 95 120];
                                    
                                end
                                app.AvanzarLabel.Visible= 'on';
                                app.GiraraladerechaLabel.Visible= 'off';
                                app.GiraralaizquierdaLabel.Visible= 'off';
                                app.DetenerLabel.Visible= 'off';
                            end
                            
                            %Movimiento 1 - Adelante
                            if movn1_op1(cmov) == 2
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN12.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [770 190 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN12.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [770 100 95 120];
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 2 - Izquierda
                            if movn1_op1(cmov) == 3
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN12.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [770 190 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN12.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [770 190 95 120];
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                                app.AvanzarLabel.Visible= 'on';
                                app.GiraraladerechaLabel.Visible= 'off';
                                app.GiraralaizquierdaLabel.Visible= 'off';
                                app.DetenerLabel.Visible= 'off';
                            end
                            %Movimiento 3 - Adelante
                            if movn1_op1(cmov) == 4
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN12.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [640 210 90 105];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN12.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [770 190 90 110];
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 4 - Derecha
                            if movn1_op1(cmov) == 5
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN12.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [640 220 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN12.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [640 210 90 105];
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 5 - Detenerse
                            if movn1_op1(cmov) == 6
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('INB12.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [640 220 90 110];
                                    clabels=clabels+5;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN12.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [640 220 90 110];
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                        end
                    end
                    
                    %Opción 3 - Nivel 1
                    if a>= 0.66 && a <= 1 %Nivel 1 - Opción 3
                        
                        %Fin del Nivel
                        if clabels > cmov
                            app.nivel2.Enable= 'on';
                            app.nivel2.Visible='on';
                            s_wind=0;
                            %Opción 3 Nivel 1 - Posición Inicial
                        elseif movn1_op1(samples_acquired/512) == movn1_op1(cmov)
                            
                            %inicial
                            if movn1_op1(cmov) == 1
                                if labels_SVM(clabels) == 0
                                    app.FONDO = imread('IN13.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [810 100 95 120];
                                    clabels=clabels+1;
                                else
                                    app.FONDO = imread('IN13.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [810 100 95 120];
                                    
                                end
                                app.AvanzarLabel.Visible= 'on';
                                app.GiraraladerechaLabel.Visible= 'off';
                                app.GiraralaizquierdaLabel.Visible= 'off';
                                app.DetenerLabel.Visible= 'off';
                            end
                            
                            %Movimiento 1 - Adelante
                            if movn1_op1(cmov) == 2
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN13.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [810 240 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN13.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [810 100 95 120];
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 2 - Izquierda
                            if movn1_op1(cmov) == 3
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN13.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [810 260 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN13.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [810 240 95 120];
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 3 - Adelante
                            if movn1_op1(cmov) == 4
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN13.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [650 260 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN13.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [810 260 90 110];
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 4 - Izquierda
                            if movn1_op1(cmov) == 5
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN13.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [650 250 120 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN13.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [650 260 90 110];
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 5 - Detenerse
                            if movn1_op1(cmov) == 6
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('INB13.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [650 250 120 120];
                                    clabels=clabels+5;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN13.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [650 250 120 120];
                                    movn1_op1=[movn1_op1(1:cmov-1) movn1_op1(cmov) movn1_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                        end
                    end
                    
                    cmov=cmov+1;
                    %Filtrado y Vector de eventos
                    s_filt= filtfilt(hp_filt,double(sig(1:samples_acquired,:))); %señal filtrada
                    v_event(:,:,:)= s_filt(samples_acquired-512+1:samples_acquired,:); %vector de enventos
                    
                    %Extracción de Caradcterísticas
                    v_mean = mean(v_event,2); %promedio
                    p_mean = permute(v_mean,[2 1 3]);
                    v_mean = squeeze(p_mean)'; %vector promedio
                    
                    v_std = std(v_event,[],2); %desviasión estándar
                    p_std = permute(v_std,[2 1 3]);
                    v_std = squeeze(p_std)'; %vector desviasión estándar
                    
                    vt_psd= squeeze(v_event);
                    v_psd= pwelch(vt_psd,512,256,1); %vector densidad espectral de potencia
                    
                    %Vector de Características
                    v_Carac = [v_mean,v_std,v_psd];
                    
                    Mdl_LDA = LDA_Model.Trained{1};
                    %Labels
                    labels_SVM(clabels) = predict(Mdl_LDA,v_Carac); %Prediction
                end %END IF 2 SEG
            end %END WHILE
            
            labels_SVM;
            %save labels_SVM 
            app.gds_interface.StopDataAcquisition();
            delete(app.gds_interface);
            clearvars app.gds_interface;
            clearvars app.gusbamp_config;
            clearvars data;
            
        end

        % Button pushed function: nivel2
        function nivel2ButtonPushed(app, event)
            app.JuegoButtonGroup.Visible='off';
            app.Texto.Visible='off';
            app.stopentr_2.Visible= 'on';
            app.stopentr_2.Enable= 'on';
            app.stopentrLabel_3.Visible='on';
            app.stopentrLabel_4.Visible='on';
            app.nivel2.Enable='off';
            app.nivel2.Visible='off';
            
            %Inicio adquisición
            % objeto gtecDeviceInterface
            app.gds_interface = gtecDeviceInterface();
            
            % configuración de la conexión
            app.gds_interface.IPAddressHost = '127.0.0.1';
            app.gds_interface.IPAddressLocal = '127.0.0.1';
            app.gds_interface.LocalPort = 50224;
            app.gds_interface.HostPort = 50223;
            
            % obtener dispositivos conectados
            connected_devices = app.gds_interface.GetConnectedDevices();
            
            % configuración del objeto g.USBamp
            app.gusbamp_config = gUSBampDeviceConfiguration();
            app.gusbamp_config.Name = connected_devices(1,1).Name;
            
            % habilitar el uso de funciones de la interfaz
            app.gds_interface.DeviceConfigurations = app.gusbamp_config;
            
            % obtener canales disponibles
            available_channels = app.gds_interface.GetAvailableChannels();
            
            % configuraciones de muestreo y para adquirir 16 analog channels.
            app.gusbamp_config.SamplingRate = 256;
            app.gusbamp_config.NumberOfScans = 8;
            Fs = app.gusbamp_config.SamplingRate; %Frecuencia de muestreo
            n_scans = app.gusbamp_config.NumberOfScans; %Número de scans correspondiente a Fs
            
            app.gusbamp_config.CommonGround = false(1,4);
            app.gusbamp_config.CommonReference = false(1,4);
            app.gusbamp_config.ShortCutEnabled = false;
            app.gusbamp_config.CounterEnabled = false;
            app.gusbamp_config.TriggerEnabled = false;
            
            for i=1:size(app.gusbamp_config.Channels,2)
                if (available_channels(1,i))
                    app.gusbamp_config.Channels(1,i).Available = true; %canal disponible
                    app.gusbamp_config.Channels(1,i).Acquire = true;  %seleccionar canal para adquisición
                    % no se hace uso de filtros
                    app.gusbamp_config.Channels(1,i).BandpassFilterIndex = -1;
                    app.gusbamp_config.Channels(1,i).NotchFilterIndex = -1;
                    % no se hace uso de canal bipolar
                    app.gusbamp_config.Channels(1,i).BipolarChannel = 0;
                end
            end
            
            %  configuración para inicio de adquisición de datos
            app.gds_interface.DeviceConfigurations = app.gusbamp_config;
            app.gds_interface.SetConfiguration();
            app.gds_interface.StartDataAcquisition(); %Inicio de adquisición
            
            % adquisición data
            samples_acquired = 0;
            s_wind= 1000*Fs; %longitud de la ventana de análisis 3.3min=198seg
            sig = single(zeros(s_wind, 16)); %señal
            %Filtrado - filtro butter orden 8
            hp_filt = designfilt('highpassiir', 'FilterOrder', 8, ...
                'HalfPowerFrequency', 0.5, 'DesignMethod', 'butter', 'SampleRate',Fs);
            
            a= rand(1)
            load LDA_Model; %modelo SVM
            %             load labels;
            
            %Vector de movimientos N1 - opción 1
            movn2_op1 = [1,2,3,4,5,6,7,8,9,10,11];
            cmov= 1; %contador vector de movimientos
            
            %Labels
            labels_SVM = [0];
            clabels= 1; %contador labels
            v_event=zeros(1,512,16);
            
            
            while (samples_acquired < s_wind ) %Adquisición por 10min
                try
                    [scans_received, data] = app.gds_interface.GetData(n_scans);
                    
                    sig((samples_acquired + 1) : (samples_acquired + scans_received), :) = data;
                catch ME
                    disp(ME.message);
                    break;
                end
                
                samples_acquired = samples_acquired + scans_received;
                
                %Nivel 2
                if mod(samples_acquired,512) == 0 %adquirir cada 2 seg
                    
                    %Opción 1 - Nivel 2
                    if a>= 0 && a <= 0.33 %Nivel 1 - Opción 1
                        
                        %Fin del Nivel
                        if clabels > cmov
                            app.nivel3.Enable= 'on';
                            app.nivel3.Visible='on';
                            s_wind=0;
                            
                            %Opción 1 Nivel 2 - Posición Inicial
                        elseif movn2_op1(samples_acquired/512) == movn2_op1(cmov)
                            
                            %inicial
                            if movn2_op1(cmov) == 1
                                if labels_SVM(clabels) == 0
                                    app.FONDO = imread('IN21.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [760 122 90 110];
                                    clabels=clabels+1;
                                    
                                else
                                    
                                    app.FONDO = imread('IN21.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [760 122 90 110];
                                    
                                end
                                app.AvanzarLabel.Visible= 'on';
                                app.GiraraladerechaLabel.Visible= 'off';
                                app.GiraralaizquierdaLabel.Visible= 'off';
                                app.DetenerLabel.Visible= 'off';
                            end
                            
                            %Movimiento 1 - Adelante
                            if movn2_op1(cmov) == 2
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN21.PNG');
                              	    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                       	            imshow(app.FONDO,'Parent',app.UIAxes2);
                              	    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [915 125 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN21.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [760 122 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end        
                            
                            %Movimiento 2 - Izquierda
                            if movn2_op1(cmov) == 3
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN21.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [905 125 95 130];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN21.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [915 125 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end       
                            %Movimiento 3 - Adelante
                            if movn2_op1(cmov) == 4
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN21.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [905 258 95 130];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN21.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [905 125 95 130];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 4 - Izquierda
                            if movn2_op1(cmov) == 5
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN21.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [905 265 90 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN21.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [905 258 95 130];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 5 - Adelante
                            if movn2_op1(cmov) == 6
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN21.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [755 290 90 100];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN21.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [905 265 90 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 6 - Detenerse
                            if movn2_op1(cmov) == 7
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('IN21B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [755 290 90 100];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN21.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [755 290 90 100];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                            
                            %Movimiento 7 - Derecha
                            if movn2_op1(cmov) == 8
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN21B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [740 290 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN21B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [755 290 90 100];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 8 - Adelante
                            if movn2_op1(cmov) == 9
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN21B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [740 390 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN21B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [740 290 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 9 - Adelante
                            if movn2_op1(cmov) == 10
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN21B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [740 460 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN21B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [740 390 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 10 - Detenerse
                            if movn2_op1(cmov) == 11
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('IN21B2.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [740 460 95 120];
                                    clabels=clabels+5;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN21B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [740 460 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                        end
                    end
                    
                    %Opción 2 - Nivel 2
                    if a>= 0.33 && a <= 0.66
                        
                        %Fin del Nivel
                        if clabels > cmov
                            app.nivel3.Enable= 'on';
                            app.nivel3.Visible='on';
                            s_wind=0;
                            
                            %Opción 2 Nivel 2
                        elseif movn2_op1(samples_acquired/512) == movn2_op1(cmov)
                            
                            %Posición inicial
                            if movn2_op1(cmov) == 1
                                
                                if labels_SVM(clabels) == 0
                                    app.FONDO = imread('IN22.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [870 100 95 120];
                                    clabels=clabels+1;
                                else
                                    app.FONDO = imread('IN22.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [870 100 95 120];
                                    
                                end
                                app.AvanzarLabel.Visible= 'off';
                                app.GiraraladerechaLabel.Visible= 'off';
                                app.GiraralaizquierdaLabel.Visible= 'on';
                                app.DetenerLabel.Visible= 'off';
                            end
                            
                            %Movimiento 1 - Izquierda
                            if movn2_op1(cmov) == 2
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN22.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [870 100 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN22.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [870 100 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 2 - Adelante
                            if movn2_op1(cmov) == 3
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN22.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [690 100 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN22.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [870 100 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 3 - Derecha
                            if movn2_op1(cmov) == 4
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN22.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [690 100 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN22.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [690 100 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 4 - Adelante
                            if movn2_op1(cmov) == 5
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN22.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [690 250 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN22.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [690 100 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 5 - Detenerse
                            if movn2_op1(cmov) == 6
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('IN22B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [690 250 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN22.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [690 250 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                            
                            %Movimiento 6 - Derecha
                            if movn2_op1(cmov) == 7
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN22B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [690 270 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN22B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [690 250 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 7 - Adelante
                            if movn2_op1(cmov) == 8
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN22B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [850 280 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN22B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [690 270 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 8 - Izquierda
                            if movn2_op1(cmov) == 9
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN22B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [850 280 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN22B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [850 280 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 9 - Adelante
                            if movn2_op1(cmov) == 10
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN22B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [850 370 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN22B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [850 280 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 10 - Detenerse
                            if movn2_op1(cmov) == 11
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('IN22B2.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [850 370 95 120];
                                    clabels=clabels+5;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN22B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [850 370 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                        end
                    end
                    
                    %Opción 3 - Nivel 2
                    if a>= 0.66 && a <= 1
                        
                        %Fin del Nivel
                        if clabels > cmov
                            app.nivel3.Enable= 'on';
                            app.nivel3.Visible='on';
                            s_wind=0;
                            
                            %Opción 3 Nivel 2
                        elseif movn2_op1(samples_acquired/512) == movn2_op1(cmov)
                            
                            %Posición inicial
                            if movn2_op1(cmov) == 1
                                if labels_SVM(clabels) == 0
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [710 70 95 120];
                                    clabels=clabels+1;
                                else
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [710 70 95 120];
                                    
                                end
                                app.AvanzarLabel.Visible= 'on';
                                app.GiraraladerechaLabel.Visible= 'off';
                                app.GiraralaizquierdaLabel.Visible= 'off';
                                app.DetenerLabel.Visible= 'off';
                            end
                            
                            %Movimiento 1 - Adelante
                            if movn2_op1(cmov) == 2
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [710 150 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [710 70 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 2 - Izquierda
                            if movn2_op1(cmov) == 3
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [710 150 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [710 150 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 3 - Adelante
                            if movn2_op1(cmov) == 4
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [500 160 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [710 150 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 4 - Izquierda
                            if movn2_op1(cmov) == 5
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [500 160 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [500 160 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 5 - Adelante
                            if movn2_op1(cmov) == 6
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [500 340 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [500 160 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 6 - Detenerse
                            if movn2_op1(cmov) == 7
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('IN23B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [500 340 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN23.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [500 340 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                            
                            %Movimiento 7 - Derecha
                            if movn2_op1(cmov) == 8
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN23B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [500 340 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN23B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [500 340 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 8 - Adelante
                            if movn2_op1(cmov) == 9
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN23B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [700 360 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN23B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [500 340 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 9 - Derecha
                            if movn2_op1(cmov) == 10
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN23B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [705 360 120 140];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN23B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [700 360 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 10 - Detenerse
                            if movn2_op1(cmov) == 11
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('IN23B2.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [705 360 120 140];
                                    clabels=clabels+5;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN23B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [705 360 120 140];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                        end
                    end
                    
                    %Filtrado y Vector de eventos
                    cmov=cmov+1;
                    s_filt= filtfilt(hp_filt,double(sig(1:samples_acquired,:))); %señal filtrada
                    v_event(:,:,:)= s_filt(samples_acquired-512+1:samples_acquired,:); %vector de enventos
                    
                    %Extracción de Caradcterísticas
                    v_mean = mean(v_event,2); %promedio
                    p_mean = permute(v_mean,[2 1 3]);
                    v_mean = squeeze(p_mean)'; %vector promedio
                    
                    v_std = std(v_event,[],2); %desviasión estándar
                    p_std = permute(v_std,[2 1 3]);
                    v_std = squeeze(p_std)'; %vector desviasión estándar
                    
                    vt_psd= squeeze(v_event);
                    v_psd= pwelch(vt_psd,512,256,1);%vector densidad espectral de potencia
                    
                    %Vector de Características
                    v_Carac = [v_mean,v_std,v_psd];
                    
                    Mdl_LDA = LDA_Model.Trained{1};
                    %Labels
                    labels_SVM(clabels) = predict(Mdl_LDA,v_Carac); %Prediction
                    
                end %END IF 2 SEG              
            end %END WHILE
            
            labels_SVM
            %             save labels_SVM
            app.gds_interface.StopDataAcquisition();
            delete(app.gds_interface);
            clearvars app.gds_interface;
            clearvars app.gusbamp_config;
            clearvars data;
            
            
        end

        % Button pushed function: nivel3
        function nivel3ButtonPushed(app, event)
            app.JuegoButtonGroup.Visible='off';
            app.Texto.Visible='off';
            app.nivel3.Visible='off';
            app.nivel3.Enable='off';
            app.stopentr_2.Visible= 'on';
            app.stopentr_2.Enable= 'on';
            app.stopentrLabel_3.Visible='on';
            app.stopentrLabel_4.Visible='on';
            
            %Inicio adquisición
            % objeto gtecDeviceInterface
            app.gds_interface = gtecDeviceInterface();
            
            % configuración de la conexión
            app.gds_interface.IPAddressHost = '127.0.0.1';
            app.gds_interface.IPAddressLocal = '127.0.0.1';
            app.gds_interface.LocalPort = 50224;
            app.gds_interface.HostPort = 50223;
            
            % obtener dispositivos conectados
            connected_devices = app.gds_interface.GetConnectedDevices();
            
            % configuración del objeto g.USBamp
            app.gusbamp_config = gUSBampDeviceConfiguration();
            app.gusbamp_config.Name = connected_devices(1,1).Name;
            
            % habilitar el uso de funciones de la interfaz
            app.gds_interface.DeviceConfigurations = app.gusbamp_config;
            
            % obtener canales disponibles
            available_channels = app.gds_interface.GetAvailableChannels();
            
            % configuraciones de muestreo y para adquirir 16 analog channels.
            app.gusbamp_config.SamplingRate = 256;
            app.gusbamp_config.NumberOfScans = 8;
            Fs = app.gusbamp_config.SamplingRate; %Frecuencia de muestreo
            n_scans = app.gusbamp_config.NumberOfScans; %Número de scans correspondiente a Fs
            
            app.gusbamp_config.CommonGround = false(1,4);
            app.gusbamp_config.CommonReference = false(1,4);
            app.gusbamp_config.ShortCutEnabled = false;
            app.gusbamp_config.CounterEnabled = false;
            app.gusbamp_config.TriggerEnabled = false;
            
            for i=1:size(app.gusbamp_config.Channels,2)
                if (available_channels(1,i))
                    app.gusbamp_config.Channels(1,i).Available = true; %canal disponible
                    app.gusbamp_config.Channels(1,i).Acquire = true;  %seleccionar canal para adquisición
                    % no se hace uso de filtros
                    app.gusbamp_config.Channels(1,i).BandpassFilterIndex = -1;
                    app.gusbamp_config.Channels(1,i).NotchFilterIndex = -1;
                    % no se hace uso de canal bipolar
                    app.gusbamp_config.Channels(1,i).BipolarChannel = 0;
                end
            end
            
            %  configuración para inicio de adquisición de datos
            app.gds_interface.DeviceConfigurations = app.gusbamp_config;
            app.gds_interface.SetConfiguration();
            app.gds_interface.StartDataAcquisition(); %Inicio de adquisición
            
            % adquisición data
            samples_acquired = 0;
            s_wind= 1000*Fs; %longitud de la ventana de análisis 3.3min=198seg
            sig = single(zeros(s_wind, 16)); %señal
            %Filtrado - filtro butter orden 8
            hp_filt = designfilt('highpassiir', 'FilterOrder', 8, ...
                'HalfPowerFrequency', 0.5, 'DesignMethod', 'butter', 'SampleRate',Fs);
            
            a= rand(1)
            load LDA_Model; %modelo LDA
            %             load labels;
            
            %Vector de movimientos N2
            movn2_op1 = [1,2,3,4,5,6,7,8,9,10,11];
            cmov= 1; %contador vector de movimientos
            
            %Labels
            labels_SVM = [0];
            clabels= 1; %contador labels
            v_event=zeros(1,512,16);%vector de eventos
            
            
            while (samples_acquired < s_wind ) %Adquisición por 10min
                try
                    [scans_received, data] = app.gds_interface.GetData(n_scans);
                    
                    sig((samples_acquired + 1) : (samples_acquired + scans_received), :) = data;
                catch ME
                    disp(ME.message);
                    break;
                end
                
                samples_acquired = samples_acquired + scans_received;
                
                %Nivel 3
                if mod(samples_acquired,512) == 0 %adquirir cada 2 seg
                    
                    %Opción 1 - Nivel 3
                    if a>= 0 && a <= 0.33
                        
                        %Fin del Nivel
                        if clabels > cmov
                            app.bvack.Enable= 'on';
                            app.bvack.Visible= 'on';
                            s_wind=0;
                            
                            %Opción 1 Nivel 3
                        elseif movn2_op1(samples_acquired/512) == movn2_op1(cmov)
                            
                            %Posición inicial
                            if movn2_op1(cmov) == 1
                                if labels_SVM(clabels) == 0
                                    app.FONDO = imread('IN31.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [600 230 95 120];
                                    clabels=clabels+1;
                                else
                                    app.FONDO = imread('IN31.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [600 230 95 120];
                                    
                                end
                                app.AvanzarLabel.Visible= 'on';
                                app.GiraraladerechaLabel.Visible= 'off';
                                app.GiraralaizquierdaLabel.Visible= 'off';
                                app.DetenerLabel.Visible= 'off';
                            end
                            
                            %Movimiento 1 - Adelante
                            if movn2_op1(cmov) == 2
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN31.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [750 230 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN31.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [600 230 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 2 - Adelante
                            if movn2_op1(cmov) == 3
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN31.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [840 230 95 120];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN31.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [750 230 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 3 - Derecha
                            if movn2_op1(cmov) == 4
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN31.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [835 230 105 105];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN31.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [840 230 95 120];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 4 - Adelante
                            if movn2_op1(cmov) == 5
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN31.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [835 140 105 105];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN31.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [835 230 105 105];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 5 - Detenerse
                            if movn2_op1(cmov) == 6
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('IN31B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [835 140 105 105];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN31.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [835 140 105 105];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                            
                            %Movimiento 6 - Derecha
                            if movn2_op1(cmov) == 7
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN31B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [835 140 95 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN31B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [835 140 105 105];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 7 - Izquierda
                            if movn2_op1(cmov) == 8
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN31B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [830 140 95 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN31B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [835 140 95 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 8 - Adelante
                            if movn2_op1(cmov) == 9
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN31B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [830 220 95 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN31B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [830 140 95 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 9 - Adelante
                            if movn2_op1(cmov) == 10
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN31B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [830 295 95 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN31B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [830 220 95 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 10 - Detenerse
                            if movn2_op1(cmov) == 11
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('IN31B2.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [830 295 95 110];
                                    clabels=clabels+5;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN31B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [830 295 95 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                        end
                    end
                    
                    %Opción 2 - Nivel 3
                    if a>= 0.33 && a <= 0.66
                        
                        %Fin del Nivel
                        if clabels > cmov
                            app.bvack.Enable= 'on';
                            app.bvack.Visible= 'on';
                            s_wind=0;
                            
                            %Opción 2 Nivel 3
                        elseif movn2_op1(samples_acquired/512) == movn2_op1(cmov)
                            
                            %Posición inicial
                            if movn2_op1(cmov) == 1
                                if labels_SVM(clabels) == 0
                                    app.FONDO = imread('IN32.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [720 280 110 130];
                                    clabels=clabels+1;
                                else
                                    app.FONDO = imread('IN32.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [720 280 110 130];
                                    
                                end
                                app.AvanzarLabel.Visible= 'off';
                                app.GiraraladerechaLabel.Visible= 'off';
                                app.GiraralaizquierdaLabel.Visible= 'on';
                                app.DetenerLabel.Visible= 'off';
                            end
                            
                            %Movimiento 1 - Izquierda
                            if movn2_op1(cmov) == 2
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN32.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [710 280 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN32.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [720 280 110 130];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 2 - Adelante
                            if movn2_op1(cmov) == 3
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN32.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [550 280 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN32.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [710 280 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                                
                            end    
                            %Movimiento 3 - Derecha
                            if movn2_op1(cmov) == 4
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN32.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [550 280 120 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN32.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [550 280 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 4 - Adelante
                            if movn2_op1(cmov) == 5
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN32.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [550 210 100 100];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN32.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [550 280 120 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 5 - Detenerse
                            if movn2_op1(cmov) == 6
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('IN32B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [550 210 100 100];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN32.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [550 210 100 100];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                            
                            %Movimiento 6 - Derecha
                            if movn2_op1(cmov) == 7
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN32B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [550 210 95 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN32B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [550 210 100 100];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 7 - Izquierda
                            if movn2_op1(cmov) == 8
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN32B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [540 210 95 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN32B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [550 210 95 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 8 - Adelante
                            if movn2_op1(cmov) == 9
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN32B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [540 280 95 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN32B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [540 210 95 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 9 - Adelante
                            if movn2_op1(cmov) == 10
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN32B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [540 350 95 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN32B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [540 280 95 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 10 - Detenerse
                            if movn2_op1(cmov) == 11
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('IN32B2.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [540 350 95 110];
                                    clabels=clabels+5;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN32B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [540 350 95 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                        end
                    end
                    
                    %Opción 3 - Nivel 3
                    if a>= 0.66 && a <= 1
                        
                        %Fin del Nivel
                        if clabels > cmov
                            app.bvack.Enable= 'on';
                            app.bvack.Visible= 'on';
                            s_wind=0;
                            
                            %Opción 3 Nivel 3
                        elseif movn2_op1(samples_acquired/512) == movn2_op1(cmov)
                            
                            %Posición inicial
                            if movn2_op1(cmov) == 1
                                if labels_SVM(clabels) == 0
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [860 550 90 110];
                                    clabels=clabels+1;
                                else
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [860 550 90 110];
                                    
                                end
                                app.AvanzarLabel.Visible= 'on';
                                app.GiraraladerechaLabel.Visible= 'off';
                                app.GiraralaizquierdaLabel.Visible= 'off';
                                app.DetenerLabel.Visible= 'off';
                            end
                            
                            %Movimiento 1 - Adelante
                            if movn2_op1(cmov) == 2
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [670 550 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [860 550 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 2 - Derecha
                            if movn2_op1(cmov) == 3
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [680 550 120 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [670 550 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 3 - Adelante
                            if movn2_op1(cmov) == 4
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [677 400 109 100];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [680 550 120 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 4 - Adelante
                            if movn2_op1(cmov) == 5
                                if labels_SVM(clabels) == 4
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [677 290 109 100];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [677 400 109 100];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end    
                            %Movimiento 5 - Derecha
                            if movn2_op1(cmov) == 6
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [677 280 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mf.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [677 290 109 100];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'on';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 6 - Detenerse
                            if movn2_op1(cmov) == 7
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('IN33B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [677 280 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN33.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [677 280 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                            
                            %Movimiento 7 - Izquierda
                            if movn2_op1(cmov) == 8
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN33B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [677 270 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN33B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('mi.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [677 280 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 8 - Izquierda
                            if movn2_op1(cmov) == 9
                                if labels_SVM(clabels) == 2
                                    app.FONDO = imread('IN33B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [677 270 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN33B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('m1.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [677 270 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'on';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            
                            %Movimiento 9 - Adelante
                            if movn2_op1(cmov) == 10
                                if labels_SVM(clabels) == 1
                                    app.FONDO = imread('IN33B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [640 270 90 110];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                else
                                    app.FONDO = imread('IN33B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [677 270 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'on';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                end
                            end
                            %Movimiento 10 - Detenerse
                            if movn2_op1(cmov) == 11
                                if labels_SVM(clabels) == 3
                                    app.FONDO = imread('IN33B2.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [640 270 90 110];
                                    clabels=clabels+5;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'off';
                                else
                                    app.FONDO = imread('IN33B1.PNG');
                                    set(app.UIAxes2,'BackgroundColor','[0.5686,0.8431,0.5373]');
                                    imshow(app.FONDO,'Parent',app.UIAxes2);
                                    app.MUNE = imread('md.PNG');
                                    set(app.mune,'BackgroundColor','[0.26666666,0.25555555,0.26666666]');
                                    title(app.mune, []);
                                    xlabel(app.mune, []);
                                    ylabel(app.mune, []);
                                    app.mune.XAxis.TickLabels={};
                                    app.mune.YAxis.TickLabels={};
                                    imshow(app.MUNE,'Parent',app.mune);
                                    app.mune.Position = [640 270 90 110];
                                    movn2_op1=[movn2_op1(1:cmov-1) movn2_op1(cmov) movn2_op1(cmov:end)];
                                    clabels=clabels+1;
                                    app.AvanzarLabel.Visible= 'off';
                                    app.GiraraladerechaLabel.Visible= 'off';
                                    app.GiraralaizquierdaLabel.Visible= 'off';
                                    app.DetenerLabel.Visible= 'on';
                                end
                            end
                        end
                    end
                    
                    %Filtrado y Vector de eventos
                    cmov=cmov+1;
                    s_filt= filtfilt(hp_filt,double(sig(1:samples_acquired,:))); %señal filtrada
                    v_event(:,:,:)= s_filt(samples_acquired-512+1:samples_acquired,:); %vector de enventos
                    
                    %Extracción de Caradcterísticas
                    v_mean = mean(v_event,2); %promedio
                    p_mean = permute(v_mean,[2 1 3]);
                    v_mean = squeeze(p_mean)'; %vector promedio
                    
                    v_std = std(v_event,[],2); %desviasión estándar
                    p_std = permute(v_std,[2 1 3]);
                    v_std = squeeze(p_std)'; %vector desviasión estándar
                    
                    vt_psd= squeeze(v_event);
                    v_psd= pwelch(vt_psd,512,256,1);%vector densidad espectral de potencia
                    
                    %Vector de Características
                    v_Carac = [v_mean,v_std,v_psd];
                    
                    Mdl_LDA = LDA_Model.Trained{1};
                    %Labels
                    labels_SVM(clabels) = predict(Mdl_LDA,v_Carac); %Prediction
                    
                end               
            end %END WHILE
            
            labels_SVM
            %save Labels_SVM
            app.gds_interface.StopDataAcquisition();
            delete(app.gds_interface);
            clearvars app.gds_interface;
            clearvars app.gusbamp_config;
            clearvars data;
        end

        % Button pushed function: play
        function playButtonPushed(app, event)
            %Play Label
            app.PlayLabel.Visible='on';
            app.play.Enable='off';
            %Inicio de adquisición
            % objeto gtecDeviceInterface
            app.gds_interface = gtecDeviceInterface();
            
            % configuración de la conexión
            app.gds_interface.IPAddressHost = '127.0.0.1';
            app.gds_interface.IPAddressLocal = '127.0.0.1';
            app.gds_interface.LocalPort = 50224;
            app.gds_interface.HostPort = 50223;
            
            % obtener dispositivos conectados
            connected_devices = app.gds_interface.GetConnectedDevices();
            
            % configuración del objeto g.USBamp
            app.gusbamp_config = gUSBampDeviceConfiguration();
            app.gusbamp_config.Name = connected_devices(1,1).Name;
            
            % habilitar el uso de funciones de la interfaz
            app.gds_interface.DeviceConfigurations = app.gusbamp_config;
            
            % obtener canales disponibles
            available_channels = app.gds_interface.GetAvailableChannels();
            
            % configuraciones de muestreo y para adquirir 16 analog channels.
            app.gusbamp_config.SamplingRate = 256;
            app.gusbamp_config.NumberOfScans = 8;
            Fs = app.gusbamp_config.SamplingRate; %Frecuencia de muestreo
            n_scans = app.gusbamp_config.NumberOfScans; %Número de scans correspondiente a Fs
            
            app.gusbamp_config.CommonGround = false(1,4);
            app.gusbamp_config.CommonReference = false(1,4);
            app.gusbamp_config.ShortCutEnabled = false;
            app.gusbamp_config.CounterEnabled = false;
            app.gusbamp_config.TriggerEnabled = false;
            
            for i=1:size(app.gusbamp_config.Channels,2)
                if (available_channels(1,i))
                    app.gusbamp_config.Channels(1,i).Available = true; %canal disponible
                    app.gusbamp_config.Channels(1,i).Acquire = true;  %seleccionar canal para adquisición
                    % no se hace uso de filtros
                    app.gusbamp_config.Channels(1,i).BandpassFilterIndex = -1;
                    app.gusbamp_config.Channels(1,i).NotchFilterIndex = -1;
                    % no se hace uso de canal bipolar
                    app.gusbamp_config.Channels(1,i).BipolarChannel = 0;
                end
            end
            
            %  configuración para inicio de adquisición de datos
            app.gds_interface.DeviceConfigurations = app.gusbamp_config;
            app.gds_interface.SetConfiguration();
            app.gds_interface.StartDataAcquisition(); %Inicio de adquisición
            
            % adquisición data
            samples_acquired = 0;
            s_wind= 30*Fs; %longitud de la ventana de análisis
            sig = single(zeros(s_wind, 16)) %señal
            Lsig= length(sig);
            v_time = (0:Lsig-1)/double(Fs); %tiempo de visualización
            %Filtrado - filtro butter orden 8
            hp_filt = designfilt('highpassiir', 'FilterOrder', 8, ...
                'HalfPowerFrequency', 0.5, 'DesignMethod', 'butter', 'SampleRate',Fs);
            app.ploteeg.YTickLabel= {'Ch1','Ch2','Ch3','Ch4','Ch5','Ch6','Ch7','Ch8','Ch9','Ch10','Ch11','Ch12','Ch13','Ch14','Ch15','Ch16'};
            app.ploteeg.XLim= [v_time(1),v_time(end)];
            cntw=1;
            while (samples_acquired < s_wind)
                
                try
                    [scans_received, data] = app.gds_interface.GetData(n_scans);
                    
                    sig((samples_acquired + 1) : (samples_acquired + scans_received), :) = data;
                catch ME
                    disp(ME.message);
                    break;
                end
                samples_acquired = samples_acquired + scans_received;
                
                
                %Filtrado y visualización por cada ciclo
                
                b=mod(samples_acquired,512); %ventana de análisis de 2 segundos
                if b == 0
                    if samples_acquired == 512
                        s_filt= filtfilt(hp_filt,double(sig));
                        offset=3000;
                        v_offset= (0:15)'*offset; %offset para visualización
                        m_offset= repmat(v_offset,1,s_wind); %matriz offset
                        sig_vis= s_filt + m_offset'; %señal de visualización
                        plot(app.ploteeg, v_time(1:samples_acquired),sig_vis(1:samples_acquired,:),'-k');
                        hold(app.ploteeg,'on');
                        app.ploteeg.YLim= [(m_offset(1)-offset),(m_offset(end)+offset)];
                        hold(app.ploteeg,'on');
                        plot(app.ploteeg,[v_time(samples_acquired) v_time(samples_acquired)],[m_offset(1)-offset m_offset(end)+offset],'Color',[0.3010 0.7450 0.9330],'LineWidth',1.5)%linea
                        hold(app.ploteeg,'off');
                        app.ploteeg.YTick= v_offset;
                    else              
                        s_filt= filtfilt(hp_filt,double(sig(1:samples_acquired,:))); %señal filtrada
                        sig_vis= s_filt + m_offset(:,1:samples_acquired)'; %señal de visualización
                        plot(app.ploteeg, v_time(1:samples_acquired),sig_vis(1:samples_acquired,:),'-k');
                        hold(app.ploteeg,'on');
                        app.ploteeg.YLim= [(m_offset(1)-offset),(m_offset(end)+offset)];
                        hold(app.ploteeg,'on');
                        plot(app.ploteeg,[v_time(samples_acquired) v_time(samples_acquired)],[m_offset(1)-offset m_offset(end)+offset],'Color',[0.3010 0.7450 0.9330],'LineWidth',1.5)
                        hold(app.ploteeg,'off');
                        app.ploteeg.YTick= v_offset;
                        
                    end
                end
                
                %Refresh de plot
                c=mod(samples_acquired,double(s_wind));
                if c == 0
                    samples_acquired=0;
                    cntw= cntw+1;
                end
                %Número de ventanas
                if cntw == 61 %60=30 minutos,
                    samples_acquired= s_wind +1;
                end
                
            end
           
            
            % detener la adquisición
            app.gds_interface.StopDataAcquisition();
            
            
            % clean up
            delete(app.gds_interface);
%             clearvars app.gds_interface;
%             clearvars app.gusbamp_config;
%             clearvars data;
            %clear all;
            app.play.Enable='on';
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            
        end

        % Button pushed function: stop
        function stopButtonPushed(app, event)
            app.play.Enable='on';
            % detener la adquisición
            app.gds_interface.StopDataAcquisition();
%             clearvars app.gds_interface;
%             clearvars app.gusbamp_config;
%             clearvars data;
        end

        % Button pushed function: stopentr
        function stopentrButtonPushed(app, event)
            % detener la adquisición
            app.gds_interface.StopDataAcquisition();
            clearvars app.gds_interface;
            clearvars app.gusbamp_config;
            clearvars data;
            app.Panel.Visible='on';
            app.inicioentr.Visible='on';
            app.inicioentrLabel.Visible='on';
            app.inicioentrLabel_2.Visible='on';
            app.stopentr.Enable='off';
            app.stopentr.Visible='off';
            app.stopentrLabel.Visible='off';
            app.stopentrLabel_2.Visible='off';
            app.imder = imread('blanco.PNG');
            imshow(app.imder,'Parent',app.UIAxes);
            set(app.UIAxes,'BackgroundColor','[1,1,1]');
            
        end

        % Button pushed function: stopentr_2
        function stopentr_2ButtonPushed(app, event)
            % detener la adquisición
            app.gds_interface.StopDataAcquisition();
            clearvars app.gds_interface;
            clearvars app.gusbamp_config;
            clearvars data;
            app.JuegoButtonGroup.Visible='on';
            app.Texto.Visible='on';
            app.bvack.Visible='off';
            app.stopentr_2.Visible='off';
            app.stopentrLabel_3.Visible='off';
            app.stopentrLabel_4.Visible= 'off';
            app.nivel2.Visible='off';
            app.AvanzarLabel.Visible= 'off';
            app.GiraraladerechaLabel.Visible= 'off';
            app.GiraralaizquierdaLabel.Visible= 'off';
            app.DetenerLabel.Visible= 'off';
            app.white = imread('blanco.PNG');
            imshow(app.white,'Parent',app.UIAxes2);
            imshow(app.white,'Parent',app.mune);
            set(app.UIAxes2,'BackgroundColor','[1,1,1]');
            set(app.mune,'BackgroundColor','[1,1,1]');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BCIUIFigure = uifigure;
            app.BCIUIFigure.Name = 'BCI';
            app.TabGroup = uitabgroup(app.BCIUIFigure);
            app.AdquisicinTab = uitab(app.TabGroup);
            app.AdquisicinTab.Title = 'Adquisición';
            app.AdquisicinPanel = uipanel(app.AdquisicinTab);
            app.AdquisicinPanel.Title = 'Adquisición';
            app.ploteeg = uiaxes(app.AdquisicinPanel);
            title(app.ploteeg, 'Adquisición 16 Channels')
            xlabel(app.ploteeg, 'Time (s)')
            ylabel(app.ploteeg, 'Channels')
            app.ploteeg.YLim = [0 1];
            app.ploteeg.YLimMode = 'manual';
            app.play = uibutton(app.AdquisicinTab, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.play.Icon = 'play.png';
            app.play.Text = '';
            app.stop = uibutton(app.AdquisicinTab, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stop.Icon = 'stop.png';
            app.stop.Text = '';
            app.save = uibutton(app.AdquisicinTab, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Icon = 'save.png';
            app.save.Text = '';
            app.clear = uibutton(app.AdquisicinTab, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Icon = 'clear.png';
            app.clear.Text = '';
            app.CanalesListBoxLabel = uilabel(app.AdquisicinTab);
            app.CanalesListBoxLabel.Text = 'Canales';
            app.CanalesListBox = uilistbox(app.AdquisicinTab);
            app.CanalesListBox.Items = {'Ch 1', 'Ch 2', 'Ch 3', 'Ch 4', 'Ch 5', 'Ch 6', 'Ch 7', 'Ch 8', 'Ch 9', 'Ch 10', 'Ch 11', 'Ch 12', 'Ch 13', 'Ch 14', 'Ch 15', 'Ch 16'};
            app.CanalesListBox.Multiselect = 'on';
            app.CanalesListBox.Value = {'Ch 13'};
            app.filter = uibutton(app.AdquisicinTab, 'push');
            app.filter.Icon = 'Hpfilt.PNG';
            app.filter.Text = '';
            app.load = uibutton(app.AdquisicinTab, 'push');
            app.load.Icon = 'aa.png';
            app.load.Text = '';
            app.PlayLabel = uilabel(app.AdquisicinTab);
            app.PlayLabel.Text = 'Play';
            app.StopLabel = uilabel(app.AdquisicinTab);
            app.StopLabel.Text = 'Stop';
            app.SaveLabel = uilabel(app.AdquisicinTab);
            app.SaveLabel.Text = 'Save';
            app.ClearLabel = uilabel(app.AdquisicinTab);
            app.ClearLabel.Text = 'Clear';
            app.FilterLabel = uilabel(app.AdquisicinTab);
            app.FilterLabel.Text = 'Filter';
            app.LoadLabel = uilabel(app.AdquisicinTab);
            app.LoadLabel.Text = 'Load ';
            app.EntrenamientoTab = uitab(app.TabGroup);
            app.EntrenamientoTab.Title = 'Entrenamiento';
            app.Panel = uipanel(app.EntrenamientoTab);
            app.UIAxes = uiaxes(app.Panel);
            app.inicioentr = uibutton(app.Panel, 'push');
            app.inicioentr.ButtonPushedFcn = createCallbackFcn(app, @inicioentrButtonPushed, true);
            app.inicioentr.Icon = 'play.png';
            app.inicioentr.Text = '';
            app.inicioentrLabel = uilabel(app.Panel);
            app.inicioentrLabel.Text = 'Inicio';
            app.inicioentrLabel_2 = uilabel(app.Panel);
            app.inicioentrLabel_2.Text = 'Entrenamiento';
            app.stopentrLabel = uilabel(app.Panel);
            app.stopentrLabel.Visible = 'off';
            app.stopentrLabel.Text = 'Stop';
            app.stopentrLabel_2 = uilabel(app.Panel);
            app.stopentrLabel_2.Visible = 'off';
            app.stopentrLabel_2.Text = 'Entrenamiento';
            app.stopentr = uibutton(app.Panel, 'push');
            app.stopentr.ButtonPushedFcn = createCallbackFcn(app, @stopentrButtonPushed, true);
            app.stopentr.Icon = 'stop.png';
            app.stopentr.Enable = 'off';
            app.stopentr.Visible = 'off';
            app.stopentr.Text = '';
            app.JuegoTab = uitab(app.TabGroup);
            app.JuegoTab.Title = 'Juego';
            app.UIAxes2 = uiaxes(app.JuegoTab);
            app.Texto = uitextarea(app.JuegoTab);
            app.Texto.Value = {''; ''; 'BIENVENIDO al Laberinto Wheels,'; 'en donde debes recolectar los premios en tu camino y llegar al final para ganar.'; ''; ''; 'Para iniciar el juego, oprime INICIO y una vez superado el primer nivel, sigue con los dos restantes.'; ''; ''; '¡¡Recuerda detenerte para poder recolectar los premios!!'; ''; ''; '          A JUGAR!!'};
            app.mune = uiaxes(app.JuegoTab);
            app.bvack = uibutton(app.JuegoTab, 'push');
            app.bvack.ButtonPushedFcn = createCallbackFcn(app, @bvackButtonPushed, true);
            app.bvack.Icon = 'endgame.png';
            app.bvack.Enable = 'off';
            app.bvack.Visible = 'off';
            app.bvack.Text = '';
            app.nivel2 = uibutton(app.JuegoTab, 'push');
            app.nivel2.ButtonPushedFcn = createCallbackFcn(app, @nivel2ButtonPushed, true);
            app.nivel2.Icon = 'nivel2.PNG';
            app.nivel2.Enable = 'off';
            app.nivel2.Visible = 'off';
            app.nivel2.Text = '';
            app.stopentr_2 = uibutton(app.JuegoTab, 'push');
            app.stopentr_2.ButtonPushedFcn = createCallbackFcn(app, @stopentr_2ButtonPushed, true);
            app.stopentr_2.Icon = 'stop.png';
            app.stopentr_2.Enable = 'off';
            app.stopentr_2.Visible = 'off';
            app.stopentr_2.Text = '';
            app.nivel3 = uibutton(app.JuegoTab, 'push');
            app.nivel3.ButtonPushedFcn = createCallbackFcn(app, @nivel3ButtonPushed, true);
            app.nivel3.Icon = 'nivel3.PNG';
            app.nivel3.Enable = 'off';
            app.nivel3.Visible = 'off';
            app.nivel3.Text = '';
            app.JuegoButtonGroup = uibuttongroup(app.JuegoTab);
            app.JuegoButtonGroup.Title = 'Juego';
            app.iniciojuego = uibutton(app.JuegoButtonGroup, 'push');
            app.iniciojuego.ButtonPushedFcn = createCallbackFcn(app, @iniciojuegoButtonPushed, true);
            app.iniciojuego.Text = 'INICIO';
            app.stopentrLabel_3 = uilabel(app.JuegoTab);
            app.stopentrLabel_3.Visible = 'off';
            app.stopentrLabel_3.Text = 'Stop';
            app.stopentrLabel_4 = uilabel(app.JuegoTab);
            app.stopentrLabel_4.Visible = 'off';
            app.stopentrLabel_4.Text = 'Entrenamiento';
            app.GiraraladerechaLabel = uilabel(app.JuegoTab);
            app.GiraraladerechaLabel.Visible = 'off';
            app.GiraraladerechaLabel.Text = 'Girar a la derecha';
            app.GiraralaizquierdaLabel = uilabel(app.JuegoTab);
            app.GiraralaizquierdaLabel.Visible = 'off';
            app.GiraralaizquierdaLabel.Text = 'Girar a la izquierda';
            app.AvanzarLabel = uilabel(app.JuegoTab);
            app.AvanzarLabel.Visible = 'off';
            app.AvanzarLabel.Text = 'Avanzar';
            app.DetenerLabel = uilabel(app.JuegoTab);
            app.DetenerLabel.Visible = 'off';
            app.DetenerLabel.Text = 'Detener';
        end
    end

    methods (Access = public)

        % Construct app
        function app = bciapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BCIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BCIUIFigure)
        end
    end
end